classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        thethingthatdoesthethingPanel  matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        PlotTrueFunctionButton         matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        nSpinner                       matlab.ui.control.Spinner
        nSpinnerLabel                  matlab.ui.control.Label
        coshyLabel                     matlab.ui.control.Label
        edtMin                         matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        edtMax                         matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        CalculateTnButton              matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function T1ButtonPushed(app, event)
        
        end

        % Callback function
        function T5ButtonPushed(app, event)
           
        end

        % Callback function
        function T10ButtonPushed(app, event)
       
        end

        % Button pushed function: CalculateTnButton
        function CalculateTnButtonPushed(app, event)
            n = app.nSpinner.Value;
            a = app.edtMin.Value;
            b = app.edtMax.Value;
            f = @(y) cosh(y) ;
            y = linspace(a, b, n+1);
            area = Trap(f,n,a,b,y);
            sumarea = sum(area);
            
            x = True(f,y);
            hold(app.UIAxes,"on");
            plot(app.UIAxes,y,x);
            error = abs((sinh(b) - sinh(a)) - area);
            
            app.Label.Text = app.Label.Text+"T"+num2str(n) + " - Area: " + num2str(sumarea)  +  " - Abs Error: "+ error + newline;
        end

        % Button pushed function: PlotTrueFunctionButton
        function PlotTrueFunctionButtonPushed(app, event)
            a = app.edtMin.Value;
            b = app.edtMax.Value;
            f = @(y) cosh(y); 
            n = 10000000;
            x = linspace(a, b, n);
            y = True(f,x);
            hold(app.UIAxes, "on");
            plot(app.UIAxes,x,y);
            

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            app.Label.Text = "";      
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {768, 768};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {408, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.thethingthatdoesthethingPanel = uipanel(app.LeftPanel);
            app.thethingthatdoesthethingPanel.Title = 'the thing that does the thing';
            app.CalculateTnButton = uibutton(app.thethingthatdoesthethingPanel, 'push');
            app.CalculateTnButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateTnButtonPushed, true);
            app.CalculateTnButton.Text = 'Calculate Tn';
            app.MaxEditFieldLabel = uilabel(app.thethingthatdoesthethingPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.edtMax = uieditfield(app.thethingthatdoesthethingPanel, 'numeric');
            app.edtMax.Value = 3.14159265358979;
            app.MinEditFieldLabel = uilabel(app.thethingthatdoesthethingPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.edtMin = uieditfield(app.thethingthatdoesthethingPanel, 'numeric');
            app.edtMin.Value = -1.5707963267949;
            app.coshyLabel = uilabel(app.thethingthatdoesthethingPanel);
            app.coshyLabel.Text = 'cosh(y)';
            app.nSpinnerLabel = uilabel(app.thethingthatdoesthethingPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.thethingthatdoesthethingPanel);
            app.nSpinner.Value = 1;
            app.ResetButton = uibutton(app.thethingthatdoesthethingPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PlotTrueFunctionButton = uibutton(app.thethingthatdoesthethingPanel, 'push');
            app.PlotTrueFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrueFunctionButtonPushed, true);
            app.PlotTrueFunctionButton.Text = 'Plot True Function';
            app.Label = uilabel(app.thethingthatdoesthethingPanel);
            app.Label.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end